classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TrocarButton                    matlab.ui.control.Button
        AbrirButton                     matlab.ui.control.Button
        TipoGraficoListBox              matlab.ui.control.ListBox
        TipoGraficoListBoxLabel         matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        PassaBaixaTab                   matlab.ui.container.Tab
        VerificarButton                 matlab.ui.control.Button
        LarguradabandadetransioEditField  matlab.ui.control.NumericEditField
        LarguradabandadetransioEditFieldLabel  matlab.ui.control.Label
        FrequnciadecorteEditField       matlab.ui.control.NumericEditField
        FrequnciadecorteEditFieldLabel  matlab.ui.control.Label
        FrequnciadeamostragemEditField  matlab.ui.control.NumericEditField
        FrequnciadeamostragemEditFieldLabel  matlab.ui.control.Label
        ParmetrosdofiltropassabaixaLabel  matlab.ui.control.Label
        ExecutarButton                  matlab.ui.control.Button
        JanelamentosListBox             matlab.ui.control.ListBox
        JanelamentosListBoxLabel        matlab.ui.control.Label
        MnimanabandaderejeioRsEditField  matlab.ui.control.NumericEditField
        MnimanabandaderejeioRsEditFieldLabel  matlab.ui.control.Label
        MximanabandadepassagemRpEditField  matlab.ui.control.NumericEditField
        MximanabandadepassagemRpEditFieldLabel  matlab.ui.control.Label
        AtenuaoLabel                    matlab.ui.control.Label
        PassaAltaTab                    matlab.ui.container.Tab
        ParmetrosdofiltropassaaltaLabel  matlab.ui.control.Label
        JanelamentosListBox_2           matlab.ui.control.ListBox
        JanelamentosListBox_2Label      matlab.ui.control.Label
        BandaderejeioEditField_2        matlab.ui.control.NumericEditField
        BandaderejeioEditField_2Label   matlab.ui.control.Label
        BandadepassagemEditField_2      matlab.ui.control.NumericEditField
        BandadepassagemEditField_2Label  matlab.ui.control.Label
        sEditField_2                    matlab.ui.control.NumericEditField
        sEditField_2Label               matlab.ui.control.Label
        pEditField_2                    matlab.ui.control.NumericEditField
        pEditField_2Label               matlab.ui.control.Label
        ExecutarButton_2                matlab.ui.control.Button
        AtenuaoLabel_2                  matlab.ui.control.Label
        PassaFaixaTab                   matlab.ui.container.Tab
        ParmetrosdofiltropassafaixaLabel  matlab.ui.control.Label
        RejeitaFaixaTab                 matlab.ui.container.Tab
        ParmetrosdofiltrorejeitafaixaLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        M; % Description
        w;
        W;
        hd;
        hn;
        janelas = [cellstr(['Retangular']) cellstr(['Bartlett']) cellstr(['Hann']) cellstr(['Hamming']) cellstr(['Blackman'])]
        janelas_bkp = [cellstr(['Retangular']) cellstr(['Bartlett']) cellstr(['Hann']) cellstr(['Hamming']) cellstr(['Blackman'])]
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ExecutarButton
        function chama_passabaixa(app, event)
            app.TrocarButton.Enable = true;
            app.AbrirButton.Enable = true;
            %% parametros do filtro
            freq_s = app.FrequnciadeamostragemEditField.Value;
            
            fc = app.FrequnciadecorteEditField.Value;
            
            bw_hz = app.LarguradabandadetransioEditField.Value;
            
            rp = app.MximanabandadepassagemRpEditField.Value;
            
            rs = app.MnimanabandaderejeioRsEditField.Value;
            
            
            fp = fc - bw_hz/2; % frequência limite da banda de passagem 
            fs = fc + bw_hz/2; % frequência limite da banda de transição


            ws = (2*pi*fs)/freq_s;
            wp = (2*pi*fp)/freq_s;
            wc = (ws+wp)/2;
            Bw = abs((ws - wp))/(2*pi); % largura do lóbulo principal 
            N  = ceil(3.3/(Bw));   % Comprimento da janela de Hamming
            app.M  = N-1;              % Ordem do Filtro
           
            
            %% Calc resposta impulso ideal
            n  = 0:app.M; % Vetor de tempo discreto.  
            app.hd = PB_ideal(wc,app.M); % Resposta ao impulso ideal;
            
            
            getJanelamento = get(app.JanelamentosListBox,'Value');
            
            
                index = str2num(getJanelamento(1));
                
                valor_selecionado = string(app.janelas(index))
                
                
                if valor_selecionado == 'Retangular'
                    [app.w,app.W] = rectangle_pbl(app.M);
                elseif valor_selecionado == 'Bartlett'
                    [app.w,app.W] = bartlett_pbl(app.M);
                elseif valor_selecionado == 'Hann'
                    [app.w,app.W] = hann_pbl(app.M);
                elseif valor_selecionado == 'Hamming'
                    [app.w,app.W] = hamming_pbl(app.M);
                elseif valor_selecionado == 'Blackman'
                    [app.w,app.W] = blackman_pbl(app.M);
                end
                
                
                % Truncamento da resposta ao impulso ideal.
                
                app.hn = app.hd.*app.w;
                
                
            getGrafico = get(app.TipoGraficoListBox,'Value');
            
            switch(getGrafico(1))
                case 1
                    title (app.UIAxes,"Janela", 'Interpreter','LaTex','FontSize',12);
                    axis(app.UIAxes, [0 (app.M) 0 1.1]);
                    stem(app.UIAxes,app.W,app.w);
                case 2
                    [h,f] = freqz(app.hn);
                    title (app.UIAxes,"Magnitude (resposta truncada)", 'Interpreter','LaTex','FontSize',12);
                    
                    xlabel = "Frequência (Hz)";
                    ylabel = "Magnitude (DB)";
                    plot(app.UIAxes,f*freq_s/(2*pi), 20*log10(abs(h)));
                case 3
                    [h,f] = freqz(app.hn);
                    title (app.UIAxes,"Fase (resposta truncada)", 'Interpreter','LaTex','FontSize',12);                
                    xlabel = "Frequência (Hz)";
                    ylabel = "Graus";     
                    plot(app.UIAxes,f*freq_s/(2*pi), angle(h));
                    freqz(app.hn);
                    
            end
            
            
        end

        % Button pushed function: TrocarButton
        function trocarGrafico(app, event)
            getGrafico = get(app.TipoGraficoListBox,'Value');
            
            switch(getGrafico(1))
                case 1
                    title (app.UIAxes,"Janelamento", 'Interpreter','LaTex','FontSize',12);
                    axis(app.UIAxes, [0 (app.M) 0 1.1]);
                case 2
                    title (app.UIAxes,"Magnitude e fase", 'Interpreter','LaTex','FontSize',12);  
            end
            
            if(getGrafico(1) == 2)
                
                v = 0:app.M;
                
                [f,w2] = my_fft2(v,app.w, 8000);
                
                plot(app.UIAxes, f,w2);
                
            else
                stem(app.UIAxes,app.W,app.w);
            end
            
        end

        % Button pushed function: AbrirButton
        function abrir_grafico(app, event)
            getGrafico = get(app.TipoGraficoListBox,'Value');
            
            switch(getGrafico(1))
                case 1
                    title ("Janelamento", 'Interpreter','LaTex','FontSize',12);
                    axis([0 (app.M) 0 1.1]);
                case 2
                    title ("Magnitude e fase", 'Interpreter','LaTex','FontSize',12);  
            end
            
            if(getGrafico(1) == 2)
                
                w2 = fft(app.w);
                
                plot(app.W, abs(w2))
            else
                stem(app.W,app.w);
            end
        end

        % Button pushed function: VerificarButton
        function verificar_parametros_lp(app, event)
            
            
            delta_blackman   = 0.0001;
            delta_hamming    = 0.0022;
            delta_hann       = 0.0063;
            delta_bartlett   = 0.0562;
            delta_retangular = 0.0891;
            
            Rp = app.MximanabandadepassagemRpEditField.Value;
            
            Rs = app.MnimanabandaderejeioRsEditField.Value;
            
            delta_rs = (10^(Rp/20)) - 1;
            delta_rp = 10^(Rs/-20);
            
            delta_filter = delta_rs;
            if delta_rp < delta_filter
                delta_filter = delta_rp;
            end
            
            if delta_filter > delta_retangular    % todos
                app.janelas = [cellstr(['Retangular']) cellstr(['Bartlett']) cellstr(['Hann']) cellstr(['Hamming']) cellstr(['Blackman'])];
            elseif delta_filter > delta_bartlett % só n retangular
                app.janelas = [cellstr(['Bartlett']) cellstr(['Hann']) cellstr(['Hamming']) cellstr(['Blackman'])];
                
            elseif delta_filter > delta_hann      % só não retangular e bartlett
                app.janelas = [cellstr(['Hann']) cellstr(['Hamming']) cellstr(['Blackman'])];
                
            elseif delta_filter >  delta_hamming  % só não retangular, bartlett e Hann
                app.janelas = [cellstr(['Hamming']) cellstr(['Blackman'])];
                
            elseif delta_filter >  delta_blackman % só não retangular, bartlett, Hann e Hamming
                app.janelas = [cellstr(['Blackman'])];
            else
                app.janelas = [cellstr(['Retangular']) cellstr(['Bartlett']) cellstr(['Hann']) cellstr(['Hamming']) cellstr(['Blackman'])];
                warndlg('Pico do erro de aproximação maior que o recomendado para os janelamentos definidos.','Warning');
            end
            %app.janelas = [cellstr(['Hamming']) cellstr(['Blackman'])];
            
            %app.JanelamentosListBox.Items=[cellstr(['Retangular']) cellstr(['Bartlett']) cellstr(['Hann']) cellstr(['Hamming']) cellstr(['Blackman'])];
            app.JanelamentosListBox.Items=app.janelas;
            
            app.JanelamentosListBox.Enable  = true;
            app.ExecutarButton.Enable = true;
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Vazio')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PassaBaixaTab = uitab(app.TabGroup);
            app.PassaBaixaTab.Title = 'Passa Baixa';
            app.AtenuaoLabel = uilabel(app.PassaBaixaTab);
            app.AtenuaoLabel.Text = 'Atenuação:';
            app.MximanabandadepassagemRpEditFieldLabel = uilabel(app.PassaBaixaTab);
            app.MximanabandadepassagemRpEditFieldLabel.Text = 'Máxima na banda de passagem (Rp)';
            app.MximanabandadepassagemRpEditField = uieditfield(app.PassaBaixaTab, 'numeric');
            app.MximanabandadepassagemRpEditField.Value = 0.1;
            app.MnimanabandaderejeioRsEditFieldLabel = uilabel(app.PassaBaixaTab);
            app.MnimanabandaderejeioRsEditFieldLabel.Text = 'Mínima na banda de rejeição (Rs)';
            app.MnimanabandaderejeioRsEditField = uieditfield(app.PassaBaixaTab, 'numeric');
            app.MnimanabandaderejeioRsEditField.Value = 50;
            app.JanelamentosListBoxLabel = uilabel(app.PassaBaixaTab);
            app.JanelamentosListBoxLabel.Text = 'Janelamentos';
            app.JanelamentosListBox = uilistbox(app.PassaBaixaTab);
            app.JanelamentosListBox.Items = {'Retangular', 'Bartlett', 'Hann', 'Hamming', 'Blackman'};
            app.JanelamentosListBox.ItemsData = {'1', '2', '3', '4', '5'};
            app.JanelamentosListBox.Enable = 'off';
            app.JanelamentosListBox.Value = '1';
            app.ExecutarButton = uibutton(app.PassaBaixaTab, 'push');
            app.ExecutarButton.ButtonPushedFcn = createCallbackFcn(app, @chama_passabaixa, true);
            app.ExecutarButton.Enable = 'off';
            app.ExecutarButton.Text = 'Executar';
            app.ParmetrosdofiltropassabaixaLabel = uilabel(app.PassaBaixaTab);
            app.ParmetrosdofiltropassabaixaLabel.Text = 'Parâmetros do filtro passa baixa:';
            app.FrequnciadeamostragemEditFieldLabel = uilabel(app.PassaBaixaTab);
            app.FrequnciadeamostragemEditFieldLabel.Text = 'Frequência de amostragem';
            app.FrequnciadeamostragemEditField = uieditfield(app.PassaBaixaTab, 'numeric');
            app.FrequnciadeamostragemEditField.Value = 8000;
            app.FrequnciadecorteEditFieldLabel = uilabel(app.PassaBaixaTab);
            app.FrequnciadecorteEditFieldLabel.Text = 'Frequência de corte';
            app.FrequnciadecorteEditField = uieditfield(app.PassaBaixaTab, 'numeric');
            app.FrequnciadecorteEditField.Value = 1900;
            app.LarguradabandadetransioEditFieldLabel = uilabel(app.PassaBaixaTab);
            app.LarguradabandadetransioEditFieldLabel.Text = 'Largura da banda de transição';
            app.LarguradabandadetransioEditField = uieditfield(app.PassaBaixaTab, 'numeric');
            app.LarguradabandadetransioEditField.Value = 400;
            app.VerificarButton = uibutton(app.PassaBaixaTab, 'push');
            app.VerificarButton.ButtonPushedFcn = createCallbackFcn(app, @verificar_parametros_lp, true);
            app.VerificarButton.Text = 'Verificar';
            app.PassaAltaTab = uitab(app.TabGroup);
            app.PassaAltaTab.Title = 'Passa Alta';
            app.AtenuaoLabel_2 = uilabel(app.PassaAltaTab);
            app.AtenuaoLabel_2.Text = 'Atenuação:';
            app.ExecutarButton_2 = uibutton(app.PassaAltaTab, 'push');
            app.ExecutarButton_2.Text = 'Executar';
            app.pEditField_2Label = uilabel(app.PassaAltaTab);
            app.pEditField_2Label.Text = 'ωp';
            app.pEditField_2 = uieditfield(app.PassaAltaTab, 'numeric');
            app.sEditField_2Label = uilabel(app.PassaAltaTab);
            app.sEditField_2Label.Text = 'ωs';
            app.sEditField_2 = uieditfield(app.PassaAltaTab, 'numeric');
            app.BandadepassagemEditField_2Label = uilabel(app.PassaAltaTab);
            app.BandadepassagemEditField_2Label.Text = 'Banda de passagem';
            app.BandadepassagemEditField_2 = uieditfield(app.PassaAltaTab, 'numeric');
            app.BandaderejeioEditField_2Label = uilabel(app.PassaAltaTab);
            app.BandaderejeioEditField_2Label.Text = 'Banda de rejeição';
            app.BandaderejeioEditField_2 = uieditfield(app.PassaAltaTab, 'numeric');
            app.JanelamentosListBox_2Label = uilabel(app.PassaAltaTab);
            app.JanelamentosListBox_2Label.Text = 'Janelamentos';
            app.JanelamentosListBox_2 = uilistbox(app.PassaAltaTab);
            app.JanelamentosListBox_2.Items = {'Retangular', 'Bartlett', 'Hann', 'Hamming', 'Blackman'};
            app.JanelamentosListBox_2.ItemsData = [1 2 3 4 5];
            app.JanelamentosListBox_2.Value = 1;
            app.ParmetrosdofiltropassaaltaLabel = uilabel(app.PassaAltaTab);
            app.ParmetrosdofiltropassaaltaLabel.Text = 'Parâmetros do filtro passa alta:';
            app.PassaFaixaTab = uitab(app.TabGroup);
            app.PassaFaixaTab.Title = 'Passa Faixa';
            app.ParmetrosdofiltropassafaixaLabel = uilabel(app.PassaFaixaTab);
            app.ParmetrosdofiltropassafaixaLabel.Text = 'Parâmetros do filtro passa faixa:';
            app.RejeitaFaixaTab = uitab(app.TabGroup);
            app.RejeitaFaixaTab.Title = 'Rejeita Faixa';
            app.ParmetrosdofiltrorejeitafaixaLabel = uilabel(app.RejeitaFaixaTab);
            app.ParmetrosdofiltrorejeitafaixaLabel.Text = 'Parâmetros do filtro rejeita faixa:';
            app.TipoGraficoListBoxLabel = uilabel(app.UIFigure);
            app.TipoGraficoListBoxLabel.Text = {'Tipo'; 'Grafico'};
            app.TipoGraficoListBox = uilistbox(app.UIFigure);
            app.TipoGraficoListBox.Items = {'Janela', 'Magnitude truncada', 'Fase truncada', 'Item 4'};
            app.TipoGraficoListBox.ItemsData = [1 2 3 4];
            app.TipoGraficoListBox.Value = 1;
            app.AbrirButton = uibutton(app.UIFigure, 'push');
            app.AbrirButton.ButtonPushedFcn = createCallbackFcn(app, @abrir_grafico, true);
            app.AbrirButton.Enable = 'off';
            app.AbrirButton.Text = 'Abrir';
            app.TrocarButton = uibutton(app.UIFigure, 'push');
            app.TrocarButton.ButtonPushedFcn = createCallbackFcn(app, @trocarGrafico, true);
            app.TrocarButton.Enable = 'off';
            app.TrocarButton.Text = 'Trocar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end